classdef JointsLimitsWindowApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MinEditFieldLabel       matlab.ui.control.Label
        MinJoint1Angle          matlab.ui.control.NumericEditField
        Joint1Label             matlab.ui.control.Label
        MaxEditFieldLabel       matlab.ui.control.Label
        MaxJoint1Angle          matlab.ui.control.NumericEditField
        Joint2Label             matlab.ui.control.Label
        MinEditField_2Label     matlab.ui.control.Label
        MinJoint2Angle          matlab.ui.control.NumericEditField
        MaxEditField_2Label     matlab.ui.control.Label
        MaxJoint2Angle          matlab.ui.control.NumericEditField
        Joint3Label             matlab.ui.control.Label
        MinEditField_3Label     matlab.ui.control.Label
        MinJoint3Angle          matlab.ui.control.NumericEditField
        MaxEditField_3Label     matlab.ui.control.Label
        MaxJoint3Angle          matlab.ui.control.NumericEditField
        Joint4Label             matlab.ui.control.Label
        MinEditField_4Label     matlab.ui.control.Label
        MinJoint4Angle          matlab.ui.control.NumericEditField
        MaxEditField_4Label     matlab.ui.control.Label
        MaxJoint4Angle          matlab.ui.control.NumericEditField
        Joint5Label             matlab.ui.control.Label
        MinEditField_5Label     matlab.ui.control.Label
        MinJoint5Angle          matlab.ui.control.NumericEditField
        MaxEditField_5Label     matlab.ui.control.Label
        MaxJoint5Angle          matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        Label_4                 matlab.ui.control.Label
        Label_5                 matlab.ui.control.Label
        Label_6                 matlab.ui.control.Label
        Label_7                 matlab.ui.control.Label
        Label_8                 matlab.ui.control.Label
        Label_9                 matlab.ui.control.Label
        Label_10                matlab.ui.control.Label
        InputJointsLimitsLabel  matlab.ui.control.Label
        OKButton                matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CallingApp.SliderJoint1.Limits = [app.MinJoint1Angle.Value, app.MaxJoint1Angle.Value];
            app.CallingApp.SliderJoint2.Limits = [app.MinJoint2Angle.Value, app.MaxJoint2Angle.Value];
            app.CallingApp.SliderJoint3.Limits = [app.MinJoint3Angle.Value, app.MaxJoint3Angle.Value];
            app.CallingApp.SliderJoint4.Limits = [app.MinJoint4Angle.Value, app.MaxJoint4Angle.Value];
            app.CallingApp.SliderJoint5.Limits = [app.MinJoint5Angle.Value, app.MaxJoint5Angle.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinJoint1Angle = uieditfield(app.UIFigure, 'numeric');
            app.Joint1Label = uilabel(app.UIFigure);
            app.Joint1Label.Text = 'Joint 1';
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxJoint1Angle = uieditfield(app.UIFigure, 'numeric');
            app.Joint2Label = uilabel(app.UIFigure);
            app.Joint2Label.Text = 'Joint 2';
            app.MinEditField_2Label = uilabel(app.UIFigure);
            app.MinEditField_2Label.Text = 'Min';
            app.MinJoint2Angle = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_2Label = uilabel(app.UIFigure);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxJoint2Angle = uieditfield(app.UIFigure, 'numeric');
            app.Joint3Label = uilabel(app.UIFigure);
            app.Joint3Label.Text = 'Joint 3';
            app.MinEditField_3Label = uilabel(app.UIFigure);
            app.MinEditField_3Label.Text = 'Min';
            app.MinJoint3Angle = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_3Label = uilabel(app.UIFigure);
            app.MaxEditField_3Label.Text = 'Max';
            app.MaxJoint3Angle = uieditfield(app.UIFigure, 'numeric');
            app.Joint4Label = uilabel(app.UIFigure);
            app.Joint4Label.Text = 'Joint 4';
            app.MinEditField_4Label = uilabel(app.UIFigure);
            app.MinEditField_4Label.Text = 'Min';
            app.MinJoint4Angle = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_4Label = uilabel(app.UIFigure);
            app.MaxEditField_4Label.Text = 'Max';
            app.MaxJoint4Angle = uieditfield(app.UIFigure, 'numeric');
            app.Joint5Label = uilabel(app.UIFigure);
            app.Joint5Label.Text = 'Joint 5';
            app.MinEditField_5Label = uilabel(app.UIFigure);
            app.MinEditField_5Label.Text = 'Min';
            app.MinJoint5Angle = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_5Label = uilabel(app.UIFigure);
            app.MaxEditField_5Label.Text = 'Max';
            app.MaxJoint5Angle = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '°';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '°';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '°';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '°';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '°';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '°';
            app.InputJointsLimitsLabel = uilabel(app.UIFigure);
            app.InputJointsLimitsLabel.Text = 'Input Joints Limits';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JointsLimitsWindowApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end