classdef logPlotWindowApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        JointsAngularPositionCheckBox  matlab.ui.control.CheckBox
        JointsAngularVelocityCheckBox  matlab.ui.control.CheckBox
        JointsAngularAccelerationCheckBox  matlab.ui.control.CheckBox
        PlotButton                     matlab.ui.control.Button
        AngularPositionErrorCheckBox   matlab.ui.control.CheckBox
        ControlEffortCheckBox          matlab.ui.control.CheckBox
        ChoosethegraphicstoplotLabel   matlab.ui.control.Label
        CentrifugalandCoriolisForcesCheckBox  matlab.ui.control.CheckBox
        GravitationalForcesCheckBox    matlab.ui.control.CheckBox
        MotorTorqueCheckBox            matlab.ui.control.CheckBox
        ResultantTorqueCheckBox        matlab.ui.control.CheckBox
        CartesianPathCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp; % Main app object
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, th_realizado, th_desejado, dth_realizado, dth_desejado, ddth_realizado, ddth_desejado, esforco_controle, theta_erro, G, V, tal_res, tal_motor, tout, x_realizado, y_realizado, z_realizado, x_desejado, y_desejado, z_desejado)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Delete the checkbox window
            if app.JointsAngularPositionCheckBox == 1
                plotJointsPosition(app.CallingApp.th_realizado);
            end
            
            plotGraphics(app.CallingApp.th_realizado, app.CallingApp.th_desejado, ...
                         app.CallingApp.dth_realizado, app.CallingApp.dth_desejado, ...
                         app.CallingApp.ddth_realizado, app.CallingApp.ddth_desejado, ...
                         app.CallingApp.x_realizado, app.CallingApp.y_realizado, app.CallingApp.z_realizado, ...
                         app.CallingApp.x_desejado, app.CallingApp.y_desejado, app.CallingApp.z_desejado, ...
                         app.CallingApp.esforco_controle, app.CallingApp.theta_erro, ...
                         app.CallingApp.V, app.CallingApp.G, app.CallingApp.tal_motor, ...
                         app.CallingApp.tal_res, app.CallingApp.tout, ...
                         app.JointsAngularPositionCheckBox.Value, ...
                         app.JointsAngularVelocityCheckBox.Value, ...
                         app.JointsAngularAccelerationCheckBox.Value, ...
                         app.ControlEffortCheckBox.Value, ...
                         app.AngularPositionErrorCheckBox.Value, ...
                         app.CentrifugalandCoriolisForcesCheckBox.Value, ...
                         app.GravitationalForcesCheckBox.Value, ...
                         app.MotorTorqueCheckBox.Value, ...
                         app.ResultantTorqueCheckBox.Value, ...
                         app.CartesianPathCheckBox.Value);
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JointsAngularPositionCheckBox = uicheckbox(app.UIFigure);
            app.JointsAngularPositionCheckBox.Text = 'Joints Angular Position';
            app.JointsAngularVelocityCheckBox = uicheckbox(app.UIFigure);
            app.JointsAngularVelocityCheckBox.Text = 'Joints Angular Velocity';
            app.JointsAngularAccelerationCheckBox = uicheckbox(app.UIFigure);
            app.JointsAngularAccelerationCheckBox.Text = 'Joints Angular Acceleration';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AngularPositionErrorCheckBox = uicheckbox(app.UIFigure);
            app.AngularPositionErrorCheckBox.Text = 'Angular Position Error';
            app.ControlEffortCheckBox = uicheckbox(app.UIFigure);
            app.ControlEffortCheckBox.Text = 'Control Effort';
            app.ChoosethegraphicstoplotLabel = uilabel(app.UIFigure);
            app.ChoosethegraphicstoplotLabel.Text = 'Choose the graphics to plot:';
            app.CentrifugalandCoriolisForcesCheckBox = uicheckbox(app.UIFigure);
            app.CentrifugalandCoriolisForcesCheckBox.Text = 'Centrifugal and Coriolis Forces';
            app.GravitationalForcesCheckBox = uicheckbox(app.UIFigure);
            app.GravitationalForcesCheckBox.Text = 'Gravitational Forces';
            app.MotorTorqueCheckBox = uicheckbox(app.UIFigure);
            app.MotorTorqueCheckBox.Text = 'Motor Torque';
            app.ResultantTorqueCheckBox = uicheckbox(app.UIFigure);
            app.ResultantTorqueCheckBox.Text = 'Resultant Torque';
            app.CartesianPathCheckBox = uicheckbox(app.UIFigure);
            app.CartesianPathCheckBox.Text = 'Cartesian Path';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logPlotWindowApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end