classdef runLoadTrajectoryWindowApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TrajectoryJoint1  matlab.ui.control.EditField
        Enterjoint1trajectoryindegreesLabel  matlab.ui.control.Label
        TrajectoryJoint2  matlab.ui.control.EditField
        Enterjoint2trajectoryindegreesLabel  matlab.ui.control.Label
        TrajectoryJoint3  matlab.ui.control.EditField
        Enterjoint3trajectoryindegreesLabel  matlab.ui.control.Label
        TrajectoryJoint4  matlab.ui.control.EditField
        Enterjoint4trajectoryindegreesLabel  matlab.ui.control.Label
        TrajectoryJoint5  matlab.ui.control.EditField
        Enterjoint5trajectoryindegreesLabel  matlab.ui.control.Label
        LoadButton        matlab.ui.control.Button
        CancelButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.CallingApp.trajectory = [str2num(app.TrajectoryJoint1.Value); 
                                         str2num(app.TrajectoryJoint2.Value);
                                         str2num(app.TrajectoryJoint3.Value);
                                         str2num(app.TrajectoryJoint4.Value);
                                         str2num(app.TrajectoryJoint5.Value);];
            
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrajectoryJoint1 = uieditfield(app.UIFigure, 'text');
            app.TrajectoryJoint1.Value = '[0]';
            app.Enterjoint1trajectoryindegreesLabel = uilabel(app.UIFigure);
            app.Enterjoint1trajectoryindegreesLabel.Text = 'Enter joint 1 trajectory (in degrees):';
            app.TrajectoryJoint2 = uieditfield(app.UIFigure, 'text');
            app.TrajectoryJoint2.Value = '[0]';
            app.Enterjoint2trajectoryindegreesLabel = uilabel(app.UIFigure);
            app.Enterjoint2trajectoryindegreesLabel.Text = 'Enter joint 2 trajectory (in degrees):';
            app.TrajectoryJoint3 = uieditfield(app.UIFigure, 'text');
            app.TrajectoryJoint3.Value = '[0]';
            app.Enterjoint3trajectoryindegreesLabel = uilabel(app.UIFigure);
            app.Enterjoint3trajectoryindegreesLabel.Text = 'Enter joint 3 trajectory (in degrees):';
            app.TrajectoryJoint4 = uieditfield(app.UIFigure, 'text');
            app.TrajectoryJoint4.Value = '[0]';
            app.Enterjoint4trajectoryindegreesLabel = uilabel(app.UIFigure);
            app.Enterjoint4trajectoryindegreesLabel.Text = 'Enter joint 4 trajectory (in degrees):';
            app.TrajectoryJoint5 = uieditfield(app.UIFigure, 'text');
            app.TrajectoryJoint5.Value = '[0]';
            app.Enterjoint5trajectoryindegreesLabel = uilabel(app.UIFigure);
            app.Enterjoint5trajectoryindegreesLabel.Text = 'Enter joint 5 trajectory (in degrees):';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = runLoadTrajectoryWindowApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end